classdef COVID_Detection_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DetectionAppUIFigure  matlab.ui.Figure
        TestResultTextArea           matlab.ui.control.TextArea
        TestResultTextAreaLabel      matlab.ui.control.Label
        LoadanImageforCOVID19DiagnosisButton  matlab.ui.control.Button
        ImageAxes_2                  matlab.ui.control.UIAxes
        ImageAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        net;
        Y_predict; % Description
    end
    
    methods (Access = public)
        
         function updateimage(app,imagefile)
                   
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.COVID19DetectionAppUIFigure, ME.message, 'Image Error');
                    return;
                end               
                    
              end
              
                end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
            
        end

        % Button pushed function: 
        % LoadanImageforCOVID19DiagnosisButton
        function LoadanImageforCOVID19DiagnosisButtonPushed(app, event)
            %loading trained model
%             app.net.net = load('trainedModelsvmcubic985.mat');
%              svmMDL=app.net.net.trainedModelSVMcubic985.ClassificationSVM;
             app.net.net = load('trainedModelSVMkarma.mat');
             svmMDL=app.net.net.trainedModelSVMkarma.ClassificationSVM;
%              svmMDL=app.net.net.trainedModelSVMcubic985.ClassificationSVM;
            global image1;
            [filename, pathname]=uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'});
            fullpathname = strcat(pathname, filename);
            image1=imread(fullpathname);
            [x y z]=size(image1);
if z==3
    griGoruntu=(rgb2gray(image1));
else
    griGoruntu=(image1);
end
%yourpath
run('C:\Users\ASYGL\OneDrive\Desktop\GUI\vlfeat-0.9.19\toolbox\vl_setup.m'); 
griGoruntu= imresize(griGoruntu, [400 300]);
imshow(griGoruntu,'Parent',app.ImageAxes);
griGoruntu= imsharpen(griGoruntu);
griGoruntu = imadjust(griGoruntu,[0 0.87]);
[mask,mu,v,p]=segment_em_gmm(griGoruntu,3);
griGoruntu = wiener2(mask,[4 4]);
imshow(griGoruntu,[], 'Parent',app.ImageAxes_2);
 cFeat=vl_hog(im2single((griGoruntu)),50);  
 cFeat1=reshape(cFeat,1,[]);
 [labels,~] = predict(svmMDL,cFeat1);         
 if (labels)==1
     app.TestResultTextArea.Value='Your COVID-19 result is POSITIVE';
 else
     app.TestResultTextArea.Value='Your COVID-19 result is NEGATIVE';
 end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DetectionAppUIFigure = uifigure('Visible', 'off');
            app.COVID19DetectionAppUIFigure.Name = 'COVID-19 Detection App';
            app.COVID19DetectionAppUIFigure.Icon = '2021-07-31_11-12-59.png';
            app.ImageAxes = uiaxes(app.COVID19DetectionAppUIFigure);
            title(app.ImageAxes, 'Original Image')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes_2 = uiaxes(app.COVID19DetectionAppUIFigure);
            title(app.ImageAxes_2, 'Segmented Image')
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.YTick = [];
            app.LoadanImageforCOVID19DiagnosisButton = uibutton(app.COVID19DetectionAppUIFigure, 'push');
            app.LoadanImageforCOVID19DiagnosisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanImageforCOVID19DiagnosisButtonPushed, true);
            app.LoadanImageforCOVID19DiagnosisButton.Text = 'Load an Image for COVID-19 Diagnosis';
            app.TestResultTextAreaLabel = uilabel(app.COVID19DetectionAppUIFigure);
            app.TestResultTextAreaLabel.Text = 'Test Result';
            app.TestResultTextArea = uitextarea(app.COVID19DetectionAppUIFigure);
            % Show the figure after all components are created
            app.COVID19DetectionAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID_Detection_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DetectionAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DetectionAppUIFigure)
        end
    end
end